classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CarregarImagemButton            matlab.ui.control.Button
        ModificadoLabel                 matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        Trabalho1FiltrosetcnicasbsicasLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AlgoritmosTcnicasDropDown       matlab.ui.control.DropDown
        AlgoritmosTcnicasDropDownLabel  matlab.ui.control.Label
        ProcessarButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        imgOriginal %armazena a imagem carregada
    end

    methods (Access = private)
           
       % Função para converter RGB em escala de cinza
        function out = grayscale(~,img_rgb)
            [lin, col, ~] = size(img_rgb);
            img_gray = zeros(lin, col);
            for i = 1:lin
                for j = 1:col
                    R = double(img_rgb(i,j,1));
                    G = double(img_rgb(i,j,2));
                    B = double(img_rgb(i,j,3));
                    img_gray(i,j) = 0.3 * R + 0.6 * G + 0.1 * B;
                end
            end
            img_gray = uint8(img_gray);
            out = img_gray;
        end


        function out = roberts(app, img_rgb)
            img_gray = grayscale(app, img_rgb);
            %Gx = roberts_x
            roberts_Gx = [1 0; 
                          0 -1];

            %Gy = roberts_y
            roberts_Gy = [0 1; 
                         -1 0];

            Gx = imfilter(double(img_gray), roberts_Gx,'replicate');
            Gy = imfilter(double(img_gray), roberts_Gy,'replicate');
            G = sqrt(Gx.^2 + Gy.^2);
            out = uint8(G);
        end

        function out = prewitt(app, img_rgb)
            img_gray = grayscale(app, img_rgb);
            prewitt_Gx = [-1 0 1; 
                          -1 0 1; 
                          -1 0 1];

            prewitt_Gy = [1 1 1; 
                          0 0 0; 
                         -1 -1 -1];

            Gx = imfilter(double(img_gray), prewitt_Gx,'replicate');
            Gy = imfilter(double(img_gray), prewitt_Gy,'replicate');
            G = sqrt(Gx.^2 + Gy.^2);
            out = uint8(G);
        end

        function out = sobel(app, img_rgb)
            img_gray = grayscale(app, img_rgb);
            sobel_Gx = [-1 0 1; 
                        -2 0 2;
                         1 0 1];

            sobel_Gy = [-1 -2 -1;
                        0  0  0;
                        1  2  1];

            Gx = imfilter(double(img_gray), sobel_Gx,'replicate');
            Gy = imfilter(double(img_gray), sobel_Gy,'replicate');
            G = sqrt(Gx.^2 + Gy.^2);
            out = uint8(G);
        end

        %transformação logoritmica
        function out = logTransform(app, img)
            % Verifica se a imagem é colorida
            if size(img,3) == 3
                img = grayscale(app, img); % Converte para escala de cinza usando sua própria função
            end

            img = double(img); % Converte para double para permitir operações matemáticas

            % Constante de escala (ajusta para 0-255)
            c = 255 / log(1 + max(img(:)));

            % Aplica a transformação logarítmica pixel a pixel
            [lin, col] = size(img);
            output = zeros(lin, col);

            for i = 1:lin
                for j = 1:col
                    r = img(i,j);
                    output(i,j) = c * log(1 + r);
                end
            end

            % Garante que o output esteja dentro do intervalo de 0 a 255
            out = uint8(output);
        end 

        %operações aritmeticas
        %histograma
        function hist = histograma(app, img)
            % Verifica se a imagem é colorida
            if size(img,3) == 3
                img = grayscale(app, img); % Usa sua função de conversão para cinza
            end

            % Inicializa o vetor histograma com zeros (256 níveis de cinza)
            hist = zeros(1, 256);

            % Percorre todos os pixels da imagem
            [lin, col] = size(img);

            for i = 1:lin
                for j = 1:col
                    intensidade = img(i,j);    % Pega o valor do pixel (0-255)
                    hist(intensidade + 1) = hist(intensidade + 1) + 1; 
                    % +1 porque o MATLAB não indexa zero
                end
            end
        end


        %equalização de histograma
        function out = equaliza_histograma(app, img)
                    % Verifica se é RGB e converte
                        if size(img, 3) == 3
                        img = grayscale(app, img);
                        end
                        img = double(img);
    
                        % 1. Calcula o histograma
                        [lin, col] = size(img);
                        total_pixels = lin * col;
                        hist = zeros(1, 256);

                        for i = 1:lin
                           for j = 1:col
                            valor = img(i, j);
                            hist(valor + 1) = hist(valor + 1) + 1; % +1 pois Matlab começa em 1
                            end
                        end

                    % 2. Calcula a função de distribuição acumulada (CDF)
                    cdf = cumsum(hist) / total_pixels;

                    % 3. Aplica a equalização
                    img_eq = zeros(lin, col);

                    for i = 1:lin
                        for j = 1:col
                    valor = img(i, j);
                    img_eq(i, j) = round(cdf(valor + 1) * 255);
                        end
                    end

    % 4. Converte para uint8
    out = uint8(img_eq);
end

      end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CarregarImagemButton
        function CarregarImagemButtonPushed(app, event)
            % Abre o seletor de arquivos e permite escolher uma imagem
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif', 'Imagens (.jpg,.png, .bmp,.tif)'}, ...
                                      'Selecionar uma imagem');
        
            % Verifica se o usuário selecionou um arquivo
            if isequal(file, 0)
                disp('Nenhum arquivo selecionado.');
                return;
            end
        
            % Lê a imagem do caminho selecionado
            img = imread(fullfile(path, file));
        
            app.imgOriginal = img;

            % Define a imagem no componente uiimage
            app.Image.ImageSource = img;

            % (Opcional) Exibe uma mensagem no console
            %disp(['Imagem carregada: ', fullfile(path, file)]);
        end

        % Button pushed function: ProcessarButton
        function ProcessarButtonPushed(app, event)
            opcao = app.AlgoritmosTcnicasDropDown.Value;
            img = app.imgOriginal;

            switch opcao
                case 'Escala de Cinza'
                    aux= grayscale(app,app.Image.ImageSource);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource =aux;
                 % imshow(aux);

                case 'Operador de Roberts'
                    aux = roberts(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;
                
                case 'Operador de Prewitt'
                    aux = prewitt(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;

                case 'Operador de Sobel'
                    aux = sobel(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;

                case 'Transformação Logaritimica' %arrumar função
                    aux = logTransform(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;
            
                case 'Histograma (Escala de Cinza)' %arrumar função
                    h = histograma(app, img);
                    figure;
                    bar(0:255, h);
                    title('Histograma');
                    xlabel('Intensidade de Cinza');
                    ylabel('Quantidade de Pixels');

                    % Mostra a imagem original ao lado, se quiser
                    aux = grayscale(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessarButton = uibutton(app.UIFigure, 'push');
            app.ProcessarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarButtonPushed, true);
            app.ProcessarButton.Text = 'Processar';
            app.AlgoritmosTcnicasDropDownLabel = uilabel(app.UIFigure);
            app.AlgoritmosTcnicasDropDownLabel.Text = 'Algoritmos/Técnicas';
            app.AlgoritmosTcnicasDropDown = uidropdown(app.UIFigure);
            app.AlgoritmosTcnicasDropDown.Items = {'Limiarização', 'Escala de Cinza', 'Passa-Alta básico', 'Passa-Alta Alto reforço', 'Passa-Baixa Média (Básico)', 'Passa-Baixa Mediana', 'Operador de Roberts', 'Operador de Prewitt', 'Operador de Sobel', 'Transformação Logaritimica', 'Operação de Soma', 'Operação de Subtração', 'Operação de Multiplicação', 'Operação de Divisão', 'Histograma (Escala de Cinza)', 'Equalização de histograma'};
            app.AlgoritmosTcnicasDropDown.Value = 'Limiarização';
            app.Image = uiimage(app.UIFigure);
            app.Trabalho1FiltrosetcnicasbsicasLabel = uilabel(app.UIFigure);
            app.Trabalho1FiltrosetcnicasbsicasLabel.Text = 'Trabalho 1 - Filtros e técnicas básicas';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '>';
            app.Image_2 = uiimage(app.UIFigure);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.ModificadoLabel = uilabel(app.UIFigure);
            app.ModificadoLabel.Text = 'Modificado';
            app.CarregarImagemButton = uibutton(app.UIFigure, 'push');
            app.CarregarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImagemButtonPushed, true);
            app.CarregarImagemButton.Text = 'Carregar Imagem';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end